classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HistogramTab               matlab.ui.container.Tab
        ImagePanel                 matlab.ui.container.Panel
        imgAxes                    matlab.ui.control.UIAxes
        ImageUploadButton          matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        histAxes                   matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
        HistogramEqualizationTab   matlab.ui.container.Tab
        ResultPanel                matlab.ui.container.Panel
        imgAxesHistEqResult        matlab.ui.control.UIAxes
        ResultHistogramPanel_2     matlab.ui.container.Panel
        histAxesHistEqResult       matlab.ui.control.UIAxes
        TargetHistogramPanel_2     matlab.ui.container.Panel
        histAxesHistEqTarget       matlab.ui.control.UIAxes
        TargetPanel_4              matlab.ui.container.Panel
        imgAxesHistEqTarget        matlab.ui.control.UIAxes
        ImageUploadButton_2        matlab.ui.control.Button
        HistogramSpecificationTab  matlab.ui.container.Tab
        ProcessimageButton         matlab.ui.control.Button
        InputreferenceimageButton  matlab.ui.control.Button
        InputtargetimageButton     matlab.ui.control.Button
        ResultHistogramPanel       matlab.ui.container.Panel
        histAxesHistSpecRes        matlab.ui.control.UIAxes
        ResultPanel_2              matlab.ui.container.Panel
        imgAxesHistSpecRes         matlab.ui.control.UIAxes
        ReferenceHistogramPanel    matlab.ui.container.Panel
        histAxesHistSpecRef        matlab.ui.control.UIAxes
        ReferencePanel             matlab.ui.container.Panel
        imgAxesHistSpecRef         matlab.ui.control.UIAxes
        TargetHistogramPanel       matlab.ui.container.Panel
        histAxesHistSpecTarget     matlab.ui.control.UIAxes
        TargetPanel_2              matlab.ui.container.Panel
        imgAxesHistSpecTarget      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgHistSpecTarget % Description
        imgHistSpecRef
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageUploadButton
        function ImageUploadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            imshow(img,'Parent',app.imgAxes);
            imhist(img);
            axes(app.histAxes);
            
        end

        % Button pushed function: ImageUploadButton_2
        function ImageUploadButton_2Pushed(app, event)
            addpath("src\");
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            imshow(img,'Parent',app.imgAxesHistEqTarget);
            %axes(app.histAxesHistEqTarget);
            %imhist(img);
            bar(app.histAxesHistEqTarget,imhist(img));
            ret = histEqualization(img);
            %axes(app.histAxesHistEqResult);
            %imhist(ret);
            bar(app.histAxesHistEqResult,imhist(ret));
            imshow(ret,'Parent',app.imgAxesHistEqResult);
        end

        % Button pushed function: InputtargetimageButton
        function InputtargetimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            app.imgHistSpecTarget=imread(fullfile(path, file));
            imshow(app.imgHistSpecTarget,'Parent',app.imgAxesHistSpecTarget);
            bar(app.histAxesHistSpecTarget,imhist(app.imgHistSpecTarget));
        end

        % Button pushed function: InputreferenceimageButton
        function InputreferenceimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            app.imgHistSpecRef=imread(fullfile(path, file));
            imshow(app.imgHistSpecRef,'Parent',app.imgAxesHistSpecRef);
            bar(app.histAxesHistSpecRef,imhist(app.imgHistSpecRef));
        end

        % Button pushed function: ProcessimageButton
        function ProcessimageButtonPushed(app, event)
            addpath("src\");
            if (isempty(app.imgHistSpecTarget)||isempty(app.imgHistSpecRef))
                return;
            end
            [result,hist]=histSpecification(app.imgHistSpecTarget,app.imgHistSpecRef);
            imshow(result,'Parent',app.imgAxesHistSpecRes);
            %axes(app.histAxesHistSpecRes);
            bar(app.histAxesHistSpecRes,imhist(result));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.Panel_2 = uipanel(app.HistogramTab);
            app.Panel_2.Title = 'Panel';
            app.histAxes = uiaxes(app.Panel_2);
            title(app.histAxes, 'Title')
            xlabel(app.histAxes, 'X')
            ylabel(app.histAxes, 'Y')
            zlabel(app.histAxes, 'Z')
            app.ImageUploadButton = uibutton(app.HistogramTab, 'push');
            app.ImageUploadButton.ButtonPushedFcn = createCallbackFcn(app, @ImageUploadButtonPushed, true);
            app.ImageUploadButton.Text = 'Image Upload';
            app.ImagePanel = uipanel(app.HistogramTab);
            app.ImagePanel.Title = 'Image';
            app.imgAxes = uiaxes(app.ImagePanel);
            app.imgAxes.XTick = [];
            app.imgAxes.YTick = [];
            app.imgAxes.ZTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.ImageUploadButton_2 = uibutton(app.HistogramEqualizationTab, 'push');
            app.ImageUploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImageUploadButton_2Pushed, true);
            app.ImageUploadButton_2.Text = 'Image Upload';
            app.TargetPanel_4 = uipanel(app.HistogramEqualizationTab);
            app.TargetPanel_4.Title = 'Target';
            app.imgAxesHistEqTarget = uiaxes(app.TargetPanel_4);
            app.imgAxesHistEqTarget.XTick = [];
            app.imgAxesHistEqTarget.YTick = [];
            app.imgAxesHistEqTarget.ZTick = [];
            app.TargetHistogramPanel_2 = uipanel(app.HistogramEqualizationTab);
            app.TargetHistogramPanel_2.Title = 'Target Histogram';
            app.histAxesHistEqTarget = uiaxes(app.TargetHistogramPanel_2);
            xlabel(app.histAxesHistEqTarget, 'Pixel Intensity')
            ylabel(app.histAxesHistEqTarget, 'Frequency')
            zlabel(app.histAxesHistEqTarget, 'Z')
            app.ResultHistogramPanel_2 = uipanel(app.HistogramEqualizationTab);
            app.ResultHistogramPanel_2.Title = 'Result Histogram';
            app.histAxesHistEqResult = uiaxes(app.ResultHistogramPanel_2);
            xlabel(app.histAxesHistEqResult, 'Pixel Intensity')
            ylabel(app.histAxesHistEqResult, 'Frequency')
            zlabel(app.histAxesHistEqResult, 'Z')
            app.ResultPanel = uipanel(app.HistogramEqualizationTab);
            app.ResultPanel.Title = 'Result';
            app.imgAxesHistEqResult = uiaxes(app.ResultPanel);
            app.imgAxesHistEqResult.XTick = [];
            app.imgAxesHistEqResult.YTick = [];
            app.imgAxesHistEqResult.ZTick = [];
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.TargetPanel_2 = uipanel(app.HistogramSpecificationTab);
            app.TargetPanel_2.Title = 'Target';
            app.imgAxesHistSpecTarget = uiaxes(app.TargetPanel_2);
            app.imgAxesHistSpecTarget.XTick = [];
            app.imgAxesHistSpecTarget.YTick = [];
            app.imgAxesHistSpecTarget.ZTick = [];
            app.TargetHistogramPanel = uipanel(app.HistogramSpecificationTab);
            app.TargetHistogramPanel.Title = 'Target Histogram';
            app.histAxesHistSpecTarget = uiaxes(app.TargetHistogramPanel);
            xlabel(app.histAxesHistSpecTarget, 'Pixel Intensity')
            ylabel(app.histAxesHistSpecTarget, 'Frequency')
            zlabel(app.histAxesHistSpecTarget, 'Z')
            app.ReferencePanel = uipanel(app.HistogramSpecificationTab);
            app.ReferencePanel.Title = 'Reference';
            app.imgAxesHistSpecRef = uiaxes(app.ReferencePanel);
            app.imgAxesHistSpecRef.XTick = [];
            app.imgAxesHistSpecRef.YTick = [];
            app.imgAxesHistSpecRef.ZTick = [];
            app.ReferenceHistogramPanel = uipanel(app.HistogramSpecificationTab);
            app.ReferenceHistogramPanel.Title = 'Reference Histogram';
            app.histAxesHistSpecRef = uiaxes(app.ReferenceHistogramPanel);
            xlabel(app.histAxesHistSpecRef, 'Pixel Intensity')
            ylabel(app.histAxesHistSpecRef, 'Frequency')
            zlabel(app.histAxesHistSpecRef, 'Z')
            app.ResultPanel_2 = uipanel(app.HistogramSpecificationTab);
            app.ResultPanel_2.Title = 'Result';
            app.imgAxesHistSpecRes = uiaxes(app.ResultPanel_2);
            app.imgAxesHistSpecRes.XTick = [];
            app.imgAxesHistSpecRes.YTick = [];
            app.imgAxesHistSpecRes.ZTick = [];
            app.ResultHistogramPanel = uipanel(app.HistogramSpecificationTab);
            app.ResultHistogramPanel.Title = 'Result Histogram';
            app.histAxesHistSpecRes = uiaxes(app.ResultHistogramPanel);
            xlabel(app.histAxesHistSpecRes, 'Pixel Intensity')
            ylabel(app.histAxesHistSpecRes, 'Frequency')
            zlabel(app.histAxesHistSpecRes, 'Z')
            app.InputtargetimageButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.InputtargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @InputtargetimageButtonPushed, true);
            app.InputtargetimageButton.Text = 'Input target image';
            app.InputreferenceimageButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.InputreferenceimageButton.ButtonPushedFcn = createCallbackFcn(app, @InputreferenceimageButtonPushed, true);
            app.InputreferenceimageButton.Text = 'Input reference image';
            app.ProcessimageButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.ProcessimageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessimageButtonPushed, true);
            app.ProcessimageButton.Text = 'Process image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end